classdef BCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DatasetDropDownLabel            matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        ReferencevalueDropDownLabel     matlab.ui.control.Label
        ReferencevalueDropDown          matlab.ui.control.DropDown
        ModalityDropDown_2Label         matlab.ui.control.Label
        ModalityDropDown_2              matlab.ui.control.DropDown
        StopvariationEditFieldLabel     matlab.ui.control.Label
        StopvariationEditField          matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        StartButton                     matlab.ui.control.Button
        BrainConnectivityAnalysisLabel  matlab.ui.control.Label
        mostimportanthubsLabel          matlab.ui.control.Label
        mostimportanthubsTextArea       matlab.ui.control.TextArea
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        nnodesEditFieldLabel            matlab.ui.control.Label
        nnodesEditField                 matlab.ui.control.NumericEditField
        nedgesEditFieldLabel            matlab.ui.control.Label
        nedgesEditField                 matlab.ui.control.NumericEditField
        rewireprobEditFieldLabel        matlab.ui.control.Label
        rewireprobEditField             matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.DatasetDropDown.Value==1
                 [file, path] = uigetfile('*.txt');
                matrix = [path file ];
                matrix=readmatrix(matrix);
                n = size(matrix, 1);
                I = eye(n);
                if app.TypeDropDown.Value==0
                    matrix = threshold(matrix, app.ThresholdEditField.Value);
                    matrix = matrix - I;
                elseif app.TypeDropDown.Value==1
                    matrix = abs(matrix);
                    matrix = matrix - I;
                end
                
            elseif app.DatasetDropDown.Value==0
                n = app.nnodesEditField.Value;
                k = app.nedgesEditField.Value;
                beta = app.rewireprobEditField.Value;
                [graph_sw, t] = WattsStrogatz(n, k/n, beta);
                plot(graph_sw);
                matrix = make_matrix_smallworld(t);
            end

            set_variation = app.StopvariationEditField.Value;
            
            %% SI BIN DEGREE COLLAPSE oppure NO BIN DEGREE COLLAPSE
            if (app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==0 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==1) || (app.DatasetDropDown.Value==0 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==1)
            
                N = n;
                k = nnz(matrix)/2;
            
                % reference degree
                d_ref_vector = degrees_und(matrix);
                d_ref = mean(d_ref_vector);
                fprintf("Il coefficiente di degree è: %f\n", d_ref);
            
                % hubs' analysis
                local_betweenness = betweenness_bin(matrix);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = degrees_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:)
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_d=matrix;
                x=n;
                d_vector=[d_ref];
                error_d_vector = [0];
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
                indexes_d = indexes;
            
                % degree check
                n=N;
                x=n;
                for i = 1:x
                    matrix_prec = new_matrix_d;
            
                    [new_matrix_d, indexes_d, d, error_d] = collapse_nodes_d(new_matrix_d, n, d_ref, indexes_d, set_variation);
            
                    if nnz(new_matrix_d) == 0
                        new_matrix_d = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    d_vector = [d_vector d];
                    error_d_vector = [error_d_vector error_d];
                end
                indexes_d = indexes_d(1:n);
                edges_d = n;
            
                vector_edges = [N:-1:edges_d];
            
                figure,
                plot(app.UIAxes, flip(d_vector),'b', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
            
                %controllo matrice ridotta con degree
                test = 0;
                hubs_str_d = hubs_descend(1:edges_d, 1)';
                hubs_str_d = string(".") + string(hubs_str_d)+ string(".");
                count_d = zeros(edges_d, 1);
                for i = 1:edges_d
                    for j = 1:edges_d
                        test = contains(indexes_d(i), hubs_str_d(j));
                        count_d(i) = count_d(i)+test;
                    end
                end
                
                figure
                im = imagesc(1:length(new_matrix_d(:, 1)), 1:length(new_matrix_d(1, :)), new_matrix_d);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_d=NODEtoREGION(indexes_d);
                
                sz=[length(indexes_d) 1];
                varTypes = {'string'};
                tablearray=table('Size', sz, 'VariableTypes', varTypes);
                counter_length=0;
                for i=1:length(indexes_d)
                    numberspaces = isspace(regions_array_d(i));
                    if nnz(numberspaces) > 1
                       tablearray(i, :) = array2table(regions_array_d(i));
                       counter_length = counter_length+1;
                    end
                end
                app.UITable.Data = tablearray;
                
            end
            
            %% SI BIN DEGREE DELETE oppure NO BIN DEGREE DELETE
            if (app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==0 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==0) || (app.DatasetDropDown.Value==0 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==0)
            
                N = n;
                k = nnz(matrix)/2;
            
                % reference degree
                d_ref_vector = degrees_und(matrix);
                d_ref = mean(d_ref_vector);
                fprintf("Il coefficiente di degree è: %f\n", d_ref);
            
                % hubs' analysis
                local_betweenness = betweenness_bin(matrix);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = degrees_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:);
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_d=matrix;
                x=n;
                d_vector=[d_ref];
                error_d_vector = [0];
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
                indexes_d = indexes;
            
                % degree check
                n=N;
                x=n;
                for i = 1:x
                    matrix_prec = new_matrix_d;
            
                    [new_matrix_d, indexes_d, d, error_d] = delete_nodes_d(new_matrix_d, n, d_ref, indexes_d, set_variation);
            
                    if nnz(new_matrix_d) == 0
                        new_matrix_d = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    d_vector = [d_vector d];
                    error_d_vector = [error_d_vector error_d];
                end
                indexes_d = indexes_d(1:n);
                edges_d = n;
            
                vector_edges = [N:-1:edges_d];
            
                figure,
                plot(app.UIAxes, flip(d_vector),'b', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
            
                test = 0;
                hubs_str_d = hubs_descend(1:edges_d, 1)';
                hubs_str_d = string(".") + string(hubs_str_d)+ string(".");
                count_d = zeros(edges_d, 1);
                for i = 1:edges_d
                    for j = 1:edges_d
                        test = contains(indexes_d(i), hubs_str_d(j));
                        count_d(i) = count_d(i)+test;
                    end
                end
                
                figure
                im = imagesc(1:length(new_matrix_d(:, 1)), 1:length(new_matrix_d(1, :)), new_matrix_d);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
            end
            
            %% SI BIN SW COLLAPSE oppure NO BIN SW COLLAPSE
            if (app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==0 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==1) || (app.DatasetDropDown.Value==0 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==1)
                
                N = n;
                k = nnz(matrix)/2;
            
                % reference smallworldness
                sw_ref = sw(matrix, n, k);
                fprintf("Il coefficiente di small worldness è: %f\n", sw_ref);
            
                % hubs' analysis
                local_betweenness = betweenness_bin(matrix);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = degrees_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:)
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_sw=matrix;
                x=n;
                sw_vector = [sw_ref];
                error_sw_vector = [0];
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
                indexes_sw = indexes;
            
                % small worldness check
                for i = 1:x
                    matrix_prec = new_matrix_sw;
            
                    [new_matrix_sw, indexes_sw, smallworldness, error_sw] = collapse_nodes_sw(new_matrix_sw, n, sw_ref, indexes_sw, set_variation);
            
                    if nnz(new_matrix_sw) == 0
                        new_matrix_sw = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    sw_vector = [sw_vector smallworldness];
                    error_sw_vector = [error_sw_vector error_sw];
                end
                indexes_sw = indexes_sw(1:n);
                edges_sw = n;  
            
                vector_edges = [N:-1:edges_sw];
            
                figure,
                plot(app.UIAxes, flip(sw_vector),'r', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
                
                test = 0
                hubs_str_sw = hubs_descend(1:edges_sw, 1)';
                hubs_str_sw = string(".") + string(hubs_str_sw) + string(".");
                count_sw = zeros(edges_sw, 1);
                for i = 1:edges_sw
                    for j = 1:edges_sw
                        test = contains(indexes_sw(i), hubs_str_sw(j));
                        count_sw(i) = count_sw(i)+test;
                    end
                end
            
                figure
                im = imagesc(1:length(new_matrix_sw(:, 1)), 1:length(new_matrix_sw(1, :)), new_matrix_sw);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_sw=NODEtoREGION(indexes_sw)
                
                sz=[length(indexes_sw) 1];
                varTypes = {'string'};
                tablearray=table('Size', sz, 'VariableTypes', varTypes);
                counter_length=0;
                for i=1:length(indexes_sw)
                    numberspaces = isspace(regions_array_sw(i));
                    if nnz(numberspaces) > 1
                       tablearray(i, :) = array2table(regions_array_sw(i));
                       counter_length = counter_length + 1;
                    end
                end
                app.UITable.Data = tablearray;
                
            end
            
            %% SI BIN SW DELETE oppure NO BIN SW DELETE
            if (app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==0 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==0) || (app.DatasetDropDown.Value==0 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==0)
                
                N = n;
                k = nnz(matrix)/2;
            
                % reference smallworldness
                sw_ref = sw(matrix, n, k);
                fprintf("Il coefficiente di small worldness è: %f\n", sw_ref);
            
                % hubs' analysis
                local_betweenness = betweenness_bin(matrix);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = degrees_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:)
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_sw=matrix;
                x=n;
                sw_vector = [sw_ref];
                error_sw_vector = [0];
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
                indexes_sw = indexes;
            
                % small worldness check
                for i = 1:x
                    matrix_prec = new_matrix_sw;
            
                    [new_matrix_sw, indexes_sw, smallworldness, error_sw] = delete_nodes_sw(new_matrix_sw, n, sw_ref, indexes_sw, set_variation);
            
                    if nnz(new_matrix_sw) == 0
                        new_matrix_sw = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    sw_vector = [sw_vector smallworldness];
                    error_sw_vector = [error_sw_vector error_sw];
                end
                indexes_sw = indexes_sw(1:n);
                edges_sw = n;  
            
                vector_edges = [N:-1:edges_sw];
            
                figure,
                plot(app.UIAxes, flip(sw_vector),'r', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
                
                test = 0
                hubs_str_sw = hubs_descend(1:edges_sw, 1)';
                hubs_str_sw = string(".") + string(hubs_str_sw) + string(".");
                count_sw = zeros(edges_sw, 1);
                for i = 1:edges_sw
                    for j = 1:edges_sw
                        test = contains(indexes_sw(i), hubs_str_sw(j));
                        count_sw(i) = count_sw(i)+test;
                    end
                end
            
                figure
                im = imagesc(1:length(new_matrix_sw(:, 1)), 1:length(new_matrix_sw(1, :)), new_matrix_sw);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_sw=NODEtoREGION(indexes_sw)
                
            end
            
            %% SI WEI DEGREE COLLAPSE 
            if app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==1 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==1
            
                n = size(matrix, 1);
                I = eye(n);
                matrix = matrix - I;
            
                N = n;
                k = nnz(matrix)/2;
            
                % reference degree
                d_ref_vector = strengths_und(matrix);
                d_ref = mean(d_ref_vector);
                fprintf("Il coefficiente di weighted degree è: %f\n", d_ref);
            
                % hubs' analysis
                matrix_len = weight_conversion(matrix, 'lengths');
                local_betweenness = betweenness_wei(matrix_len);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = strengths_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:);
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_d = matrix;
                x = n;
                d_vector = [d_ref];
                error_d_vector = [0];
            
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
            
                indexes_d = indexes;
                
                % weighted degree check
                n = N;
                x = n;
                for i = 1:x
                    matrix_prec = new_matrix_d;
            
                    [new_matrix_d, indexes_d, d, error_d] = collapse_nodes_d_wei(new_matrix_d, n, d_ref, indexes_d, set_variation);
            
                    if nnz(new_matrix_d) == 0
                        new_matrix_d = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    d_vector = [d_vector d];
                    error_d_vector = [error_d_vector error_d];
                end
                indexes_d = indexes_d(1:n);
                edges_d = n;
            
                vector_edges = [N:-1:edges_d];
            
                figure,
                plot(app.UIAxes, flip(d_vector),'b', 'LineWidth', 2);
                set(gca, 'XDir','reverse');

                test = 0;
                hubs_str_d = hubs_descend(1:edges_d, 1)';
                hubs_str_d = string(".") + string(hubs_str_d)+ string(".");
                count_d = zeros(edges_d, 1);
                for i = 1:edges_d
                    for j = 1:edges_d
                        test = contains(indexes_d(i), hubs_str_d(j));
                        count_d(i) = count_d(i)+test;
                    end
                end
                
                figure
                im = imagesc(1:length(new_matrix_d(:, 1)), 1:length(new_matrix_d(1, :)), new_matrix_d);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_d=NODEtoREGION(indexes_d)
                
                sz=[length(indexes_d) 1];
                varTypes = {'string'};
                tablearray=table('Size', sz, 'VariableTypes', varTypes);
                counter_length=0;
                for i=1:length(indexes_d)
                    numberspaces = isspace(regions_array_d(i));
                    if nnz(numberspaces) > 1
                       tablearray(i, :) = array2table(regions_array_d(i));
                       counter_length = counter_length+1;
                    end
                end
                app.UITable.Data = tablearray;
                
            end
            
            %% SI WEI DEGREE DELETE
            if app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==1 && app.ReferencevalueDropDown.Value==1 && app.ModalityDropDown_2.Value==0
                
                n = size(matrix, 1);
                I = eye(n);
                matrix = matrix - I;
            
                N = n;
                k = nnz(matrix)/2;
            
                % reference degree
                d_ref_vector = strengths_und(matrix);
                d_ref = mean(d_ref_vector);
                fprintf("Il coefficiente di weighted degree è: %f\n", d_ref);
            
                % hubs' analysis
                matrix_len = weight_conversion(matrix, 'lengths');
                local_betweenness = betweenness_wei(matrix_len);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = strengths_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:);
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_d = matrix;
                x = n;
                d_vector = [d_ref];
                error_d_vector = [0];
            
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
            
                indexes_d = indexes;
                
                % weighted degree check
                n = N;
                x = n;
                for i = 1:x
                    matrix_prec = new_matrix_d;
            
                    [new_matrix_d, indexes_d, d, error_d] = delete_nodes_d_wei(new_matrix_d, n, d_ref, indexes_d, set_variation);
            
                    if nnz(new_matrix_d) == 0
                        new_matrix_d = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    d_vector = [d_vector d];
                    error_d_vector = [error_d_vector error_d];
                end
                indexes_d = indexes_d(1:n);
                edges_d = n;
            
                vector_edges = [N:-1:edges_d];
            
                figure,
                plot(app.UIAxes, flip(d_vector),'b', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
                
                test = 0;
                hubs_str_d = hubs_descend(1:edges_d, 1)';
                hubs_str_d = string(".") + string(hubs_str_d)+ string(".");
                count_d = zeros(edges_d, 1);
                for i = 1:edges_d
                    for j = 1:edges_d
                        test = contains(indexes_d(i), hubs_str_d(j));
                        count_d(i) = count_d(i)+test;
                    end
                end    
            
                figure
                im = imagesc(1:length(new_matrix_d(:, 1)), 1:length(new_matrix_d(1, :)), new_matrix_d);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_d=NODEtoREGION(indexes_d)
                
            end
            
            %% SI WEI SW COLLAPSE
            if app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==1 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==1
                n = size(matrix, 1);
                I = eye(n);
                matrix = matrix - I;
            
                N = n;
                k = nnz(matrix)/2;
                
                % small-worldness reference
                sw_ref = sw_wei(matrix, n, k);
                fprintf("Il coefficiente di small worldness è: %f\n", sw_ref);
            
                % hubs' analysis
                matrix_len = weight_conversion(matrix, 'lengths');
                local_betweenness = betweenness_wei(matrix_len);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = strengths_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:);
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_sw = matrix;
                x = n;
                sw_vector = [sw_ref];
                error_sw_vector = [0];
            
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
            
                indexes_sw = indexes;
            
                % small-worldness check
                for i = 1:x
            
                    matrix_prec = new_matrix_sw;
            
                    [new_matrix_sw, indexes_sw, smallworldness, error_sw] = collapse_nodes_sw_wei(new_matrix_sw, n, sw_ref, indexes_sw, set_variation);
            
                    if nnz(new_matrix_sw) == 0
                        new_matrix_sw = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    sw_vector = [sw_vector smallworldness];
                    error_sw_vector = [error_sw_vector error_sw];
                end
                indexes_sw = indexes_sw(1:n);
                edges_sw = n;  
            
                vector_edges = [N:-1:edges_sw];
            
                figure,
                plot(app.UIAxes, flip(sw_vector),'r', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
                
                test = 0
                hubs_str_sw = hubs_descend(1:edges_sw, 1)';
                hubs_str_sw = string(".") + string(hubs_str_sw) + string(".");
                count_sw = zeros(edges_sw, 1);
                for i = 1:edges_sw
                    for j = 1:edges_sw
                        test = contains(indexes_sw(i), hubs_str_sw(j));
                        count_sw(i) = count_sw(i)+test;
                    end
                end
            
                figure
                im = imagesc(1:length(new_matrix_sw(:, 1)), 1:length(new_matrix_sw(1, :)), new_matrix_sw);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_sw=NODEtoREGION(indexes_sw)

                sz=[length(indexes_sw) 1];
                varTypes = {'string'};
                tablearray=table('Size', sz, 'VariableTypes', varTypes);
                counter_length=0;
                for i=1:length(indexes_sw)
                    numberspaces = isspace(regions_array_sw(i));
                    if nnz(numberspaces) > 1
                       tablearray(i, :) = array2table(regions_array_sw(i));
                       counter_length = counter_length+1;
                    end
                end
                app.UITable.Data = tablearray;
                
            end
            
            %% SI WEI SW DELETE
            if app.DatasetDropDown.Value==1 && app.TypeDropDown.Value==1 && app.ReferencevalueDropDown.Value==0 && app.ModalityDropDown_2.Value==0
                n = size(matrix, 1);
                I = eye(n);
                matrix = matrix - I;
            
                N = n;
                k = nnz(matrix)/2;
                
                % small-worldness reference
                sw_ref = sw_wei(matrix, n, k);
                fprintf("Il coefficiente di small worldness è: %f\n", sw_ref);
            
                % hubs' analysis
                matrix_len = weight_conversion(matrix, 'lengths');
                local_betweenness = betweenness_wei(matrix_len);
                local_betweenness = local_betweenness ./ max(local_betweenness);
            
                local_degree = strengths_und(matrix);
                local_degree = (local_degree ./ max(local_degree))';
            
                hubsA = local_degree + local_betweenness;
                hubsB = [(1:n)', hubsA];
                [AS,IX] = sort(hubsA, 'descend');
                hubs_descend = hubsB(IX,:);
                toptenhubs = "." + string(hubs_descend)+ ".";
                toptenhubs = NODEtoREGION(toptenhubs);
                app.mostimportanthubsTextArea.Value = toptenhubs(1:10);
                
                % variables
                new_matrix_sw = matrix;
                x = n;
                sw_vector = [sw_ref];
                error_sw_vector = [0];
            
                A = [1:1:n]';
                indexes = string(A);
                indexes = string(".")+ indexes + string(".  ");
            
                indexes_sw = indexes;
            
                % small-worldness check
                for i = 1:x
            
                    matrix_prec = new_matrix_sw;
            
                    [new_matrix_sw, indexes_sw, smallworldness, error_sw] = delete_nodes_sw_wei(new_matrix_sw, n, sw_ref, indexes_sw, set_variation);
            
                    if nnz(new_matrix_sw) == 0
                        new_matrix_sw = matrix_prec;
                        break;
                    end
            
                    n = n-1;
                    sw_vector = [sw_vector smallworldness];
                    error_sw_vector = [error_sw_vector error_sw];
                end
                indexes_sw = indexes_sw(1:n);
                edges_sw = n;  
            
                vector_edges = [N:-1:edges_sw];
            
                figure,
                plot(app.UIAxes, flip(sw_vector),'r', 'LineWidth', 2);
                set(gca, 'XDir','reverse');
                
                test = 0
                hubs_str_sw = hubs_descend(1:edges_sw, 1)';
                hubs_str_sw = string(".") + string(hubs_str_sw) + string(".");
                count_sw = zeros(edges_sw, 1);
                for i = 1:edges_sw
                    for j = 1:edges_sw
                        test = contains(indexes_sw(i), hubs_str_sw(j));
                        count_sw(i) = count_sw(i)+test;
                    end
                end
            
                figure
                im = imagesc(1:length(new_matrix_sw(:, 1)), 1:length(new_matrix_sw(1, :)), new_matrix_sw);
                hAxes = gca;
                colormap(hAxes, pink(2000))
                
                regions_array_sw=NODEtoREGION(indexes_sw)
            end
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            if value == 0
                app.nnodesEditField.Enable = 'on';
                app.nedgesEditField.Enable = 'on';
                app.rewireprobEditField.Enable = 'on';
                app.TypeDropDown.Value = 0;
                app.TypeDropDown.Enable = 'off';
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if value == 0
                app.ThresholdEditField.Enable = 'on';
            end
        end

        % Value changed function: ModalityDropDown_2
        function ModalityDropDown_2ValueChanged(app, event)
            value = app.ModalityDropDown_2.Value;
            if value == 0
                app.UITable.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'Not defined', 'Yes', 'No'};
            app.DatasetDropDown.ItemsData = [2 1 0];
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 2;
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Binary', 'Weighted', 'Not defined'};
            app.TypeDropDown.ItemsData = [0 1 2];
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 2;
            app.ReferencevalueDropDownLabel = uilabel(app.UIFigure);
            app.ReferencevalueDropDownLabel.Text = 'Reference value';
            app.ReferencevalueDropDown = uidropdown(app.UIFigure);
            app.ReferencevalueDropDown.Items = {'Small-worldness', 'Degree', 'Not defined'};
            app.ReferencevalueDropDown.ItemsData = [0 1 2];
            app.ReferencevalueDropDown.Value = 2;
            app.ModalityDropDown_2Label = uilabel(app.UIFigure);
            app.ModalityDropDown_2Label.Text = 'Modality';
            app.ModalityDropDown_2 = uidropdown(app.UIFigure);
            app.ModalityDropDown_2.Items = {'Collapse nodes', 'Delete nodes', 'Not defined'};
            app.ModalityDropDown_2.ItemsData = [1 0 2];
            app.ModalityDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDown_2ValueChanged, true);
            app.ModalityDropDown_2.Value = 2;
            app.StopvariationEditFieldLabel = uilabel(app.UIFigure);
            app.StopvariationEditFieldLabel.Text = 'Stop variation';
            app.StopvariationEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopvariationEditField.Limits = [0 1];
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BrainConnectivityAnalysisLabel = uilabel(app.UIFigure);
            app.BrainConnectivityAnalysisLabel.Text = 'Brain Connectivity Analysis';
            app.mostimportanthubsLabel = uilabel(app.UIFigure);
            app.mostimportanthubsLabel.Text = {'10 most'; 'important'; 'hubs'};
            app.mostimportanthubsTextArea = uitextarea(app.UIFigure);
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [-1 1];
            app.ThresholdEditField.Enable = 'off';
            app.nnodesEditFieldLabel = uilabel(app.UIFigure);
            app.nnodesEditFieldLabel.Enable = 'off';
            app.nnodesEditFieldLabel.Text = 'n.nodes';
            app.nnodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.nnodesEditField.Enable = 'off';
            app.nedgesEditFieldLabel = uilabel(app.UIFigure);
            app.nedgesEditFieldLabel.Enable = 'off';
            app.nedgesEditFieldLabel.Text = 'n. edges';
            app.nedgesEditField = uieditfield(app.UIFigure, 'numeric');
            app.nedgesEditField.Enable = 'off';
            app.rewireprobEditFieldLabel = uilabel(app.UIFigure);
            app.rewireprobEditFieldLabel.Enable = 'off';
            app.rewireprobEditFieldLabel.Text = 'rewire prob.';
            app.rewireprobEditField = uieditfield(app.UIFigure, 'numeric');
            app.rewireprobEditField.Enable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Families'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parameter variation')
            xlabel(app.UIAxes, 'matrix order')
            ylabel(app.UIAxes, 'parameter value')
            app.UIAxes.XTick = [0 0.25 0.5 90];
            app.UIAxes.XTickLabel = {'0'; '0.25'; '0.5'; '90'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end